classdef vishal_ssp2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MamdaniFISUIFigure         matlab.ui.Figure
        GOButton                   matlab.ui.control.StateButton
        FanSpeedEditFieldLabel     matlab.ui.control.Label
        FanSpeedEditField          matlab.ui.control.NumericEditField
        TempSliderLabel            matlab.ui.control.Label
        TempSlider                 matlab.ui.control.Slider
        NICAssignmentLabel         matlab.ui.control.Label
        VishalPrasad18210095Label  matlab.ui.control.Label
        SSPanda17310043Label       matlab.ui.control.Label
        WindSliderLabel            matlab.ui.control.Label
        WindSlider                 matlab.ui.control.Slider
        FanSpeedGaugeLabel         matlab.ui.control.Label
        FanSpeedGauge              matlab.ui.control.Gauge
    end

    methods (Access = private)

        % Callback function: GOButton, TempSlider, WindSlider
        function CalculateTelescopeFanSpeedButtonValueChanged(app, event)
            temp = app.TempSlider.Value;
            wind = app.WindSlider.Value;
            fis = readfis('mamdani2.fis');
            inputs = [temp,wind];
            ans = evalfis(fis,inputs);
            app.FanSpeedEditField.Value = ans;
            app.FanSpeedGauge.Value = ans*100;
            
            
        end

        % Value changed function: TempSlider
        function TempSliderValueChanged(app, event)
            value = app.TempSlider.Value;
            
        end

        % Callback function
        function OutsidewindspeedKmhourEditFieldValueChanged(app, event)
            value = app.OutsidewindspeedkmhourEditField.Value;
            
        end

        % Callback function
        function SpeedIncreaseEditFieldValueChanged(app, event)
            app.windspeedcontrollerGauge.Value = app.windspeedcontrollerGauge.Value;
            
            
        end

        % Callback function
        function SpeedDecreaseEditFieldValueChanged(app, event)
            app.windspeedcontrollerGauge.Value = app.windspeedcontrollerGauge.Value;
            
            
        end

        % Callback function
        function IncreaseSpeedButtonPushed(app, event)
            app.windspeedcontrollerGauge.Value = app.OutsidewindspeedKmhourEditField.Value + 5;
        end

        % Callback function
        function DecreaseSpeedButtonPushed(app, event)
            app.windspeedcontrollerGauge.Value = app.OutsidewindspeedKmhourEditField.Value - 5;
        end

        % Callback function
        function IncreaseSpeedButtonPushed2(app, event)
            app.windspeedcontrollerGauge.Value = app.windspeedcontrollerGauge.Value + 5;
            app.OutsidewindspeedKmhourEditField.Value = app.windspeedcontrollerGauge.Value;

        end

        % Callback function
        function DecreaseSpeedButtonPushed2(app, event)
            app.windspeedcontrollerGauge.Value = app.windspeedcontrollerGauge.Value - 5;
            app.OutsidewindspeedKmhourEditField.Value = app.windspeedcontrollerGauge.Value;
        end

        % Value changed function: FanSpeedEditField
        function FanSpeedEditFieldValueChanged(app, event)
            value = app.FanSpeedEditField.Value;
            app.outputfanGauge.Value = value;
        end

        % Value changed function: WindSlider
        function WindSliderValueChanged(app, event)
            value = app.WindSlider.Value;


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MamdaniFISUIFigure = uifigure;
            app.MamdaniFISUIFigure.Name = 'Mamdani FIS';
            app.GOButton = uibutton(app.MamdaniFISUIFigure, 'state');
            app.GOButton.ValueChangedFcn = createCallbackFcn(app, @CalculateTelescopeFanSpeedButtonValueChanged, true);
            app.GOButton.Text = 'GO';
            app.FanSpeedEditFieldLabel = uilabel(app.MamdaniFISUIFigure);
            app.FanSpeedEditFieldLabel.Text = 'Fan Speed';
            app.FanSpeedEditField = uieditfield(app.MamdaniFISUIFigure, 'numeric');
            app.FanSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @FanSpeedEditFieldValueChanged, true);
            app.TempSliderLabel = uilabel(app.MamdaniFISUIFigure);
            app.TempSliderLabel.Text = 'Temp';
            app.TempSlider = uislider(app.MamdaniFISUIFigure);
            app.TempSlider.Limits = [0 60];
            app.TempSlider.ValueChangedFcn = createCallbackFcn(app, @TempSliderValueChanged, true);
            app.TempSlider.ValueChangingFcn = createCallbackFcn(app, @CalculateTelescopeFanSpeedButtonValueChanged, true);
            app.NICAssignmentLabel = uilabel(app.MamdaniFISUIFigure);
            app.NICAssignmentLabel.Text = 'NIC Assignment';
            app.VishalPrasad18210095Label = uilabel(app.MamdaniFISUIFigure);
            app.VishalPrasad18210095Label.Text = 'Vishal Prasad (18210095)';
            app.SSPanda17310043Label = uilabel(app.MamdaniFISUIFigure);
            app.SSPanda17310043Label.Text = 'SS Panda (17310043)';
            app.WindSliderLabel = uilabel(app.MamdaniFISUIFigure);
            app.WindSliderLabel.Text = 'Wind';
            app.WindSlider = uislider(app.MamdaniFISUIFigure);
            app.WindSlider.Limits = [0 150];
            app.WindSlider.ValueChangedFcn = createCallbackFcn(app, @WindSliderValueChanged, true);
            app.WindSlider.ValueChangingFcn = createCallbackFcn(app, @CalculateTelescopeFanSpeedButtonValueChanged, true);
            app.FanSpeedGaugeLabel = uilabel(app.MamdaniFISUIFigure);
            app.FanSpeedGaugeLabel.Text = 'Fan Speed';
            app.FanSpeedGauge = uigauge(app.MamdaniFISUIFigure, 'circular');
        end
    end

    methods (Access = public)

        % Construct app
        function app = vishal_ssp2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MamdaniFISUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MamdaniFISUIFigure)
        end
    end
end